classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LungCancerDetectionLabel    matlab.ui.control.Label
        OriginalImage               matlab.ui.control.UIAxes
        UploadButton                matlab.ui.control.Button
        UploadMsg                   matlab.ui.control.Label
        SegmentedImage              matlab.ui.control.UIAxes
        Largest5Objects             matlab.ui.control.UIAxes
        FinalResult                 matlab.ui.control.UIAxes
        FinalVerdictEditFieldLabel  matlab.ui.control.Label
        FinalVerdict                matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            startingFolder = 'C:\Program Files\MATLAB';
            if ~exist(startingFolder, 'dir')
            	% If that folder doesn't exist, just start in the current folder.
            	startingFolder = pwd;
            end
            % Get the name of the file that the user wants to use.
            defaultFileName = fullfile(startingFolder, '*.*');
            [baseFileName, folder] = uigetfile(defaultFileName, 'Select a file');
            if baseFileName == 0
            	% User clicked the Cancel button.
            	return;
            end
            fullFileName = fullfile(folder, baseFileName);
            imshow(fullFileName, 'Parent', app.OriginalImage);
            
            I = imread(fullFileName);
            % Set up variables
            roundnessThreshold = 0.25; % tumors generally have some semblance of roundness
            areaThreshold = 100; % blood vessels are generally smaller than this size
            sizeOfObject = 0; % to record the largest object's size
            traced = 0; % tumors found or not found
            
            % Segment lung images using thresholding method (Otsu's)
            BW = imbinarize(I);
            imshow(BW, 'Parent', app.SegmentedImage);
            
            % Clear unnecessary part of the image using imclearborder
            objectsOnly = imclearborder(BW);
            
            % Find largest 5 objects (tumors are generally greater in size than blood vessels)
            largest5Objects = bwareafilt(objectsOnly, 5);
            imshow(largest5Objects, 'Parent', app.Largest5Objects);
            
            % Traces the exterior boundaries of objects
            [B,L] = bwboundaries(largest5Objects,'noholes');
            
            % Returns measurements for area and center of mass of region for each labeled region in the label matrix L
            stats = regionprops(L,'Area','Centroid');

            % Loop over the boundaries
            for k = 1:length(B)
            
              % Obtain (X,Y) boundary coordinates corresponding to label 'k'
              boundary = B{k};
            
              % Compute a simple estimate of the object's perimeter
              delta_sq = diff(boundary).^2;    
              perimeter = sum(sqrt(sum(delta_sq,2)));
              
              % Obtain the area calculation corresponding to label 'k'
              area = stats(k).Area;
              
              % Compute the roundness metric
              metric = 4*pi*area/perimeter^2;
              metric_string = sprintf('%2.2f',metric);
            
              % Compare against pre-defined roundness and area threshold
              % Cancer nodules should have some semblance of roundness and greater in
              % size than blood vessels (which normally are around 30 to 60 pixels in
              % size)
              if metric > roundnessThreshold
                  if area > sizeOfObject && area > areaThreshold
                      sizeOfObject = area;
                      traced = B{k};
                  end
              end
              
              % For each potential cancer nodules, list out their roundness metrics
              text(app.Largest5Objects, boundary(1,2)-35,boundary(1,1)+13,metric_string,'Color','r',...
                   'FontSize',10);
              
            end
            
            % If there is a detected cancer nodules, draw the boundary of said nodules
            % in the original CT scan image in red
            if traced ~= 0
                imshow(I);
                hold on;
                plot(traced(:,2), traced(:,1), 'r', 'LineWidth', 2);
                saveas(gcf,'plotted.jpg')
                plotted = imread('plotted.jpg');
                imshow(plotted, 'Parent', app.FinalResult);
                app.FinalVerdict.Value = 'With lung cancer';
            % Else, notify user that the CT scan belongs to healthy patient
            else
                imshow(I, 'Parent', app.FinalResult);
                app.FinalVerdict.Value = 'Without lung cancer';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LungCancerDetectionLabel = uilabel(app.UIFigure);
            app.LungCancerDetectionLabel.Text = 'Lung Cancer Detection';
            app.OriginalImage = uiaxes(app.UIFigure);
            title(app.OriginalImage, 'Original Image')
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.UploadMsg = uilabel(app.UIFigure);
            app.UploadMsg.Text = 'Click on the button to upload a CT scan image: ';
            app.SegmentedImage = uiaxes(app.UIFigure);
            title(app.SegmentedImage, 'Segmented Image')
            app.Largest5Objects = uiaxes(app.UIFigure);
            title(app.Largest5Objects, 'Largest 5 Objects')
            app.FinalResult = uiaxes(app.UIFigure);
            title(app.FinalResult, 'Final Result')
            app.FinalVerdictEditFieldLabel = uilabel(app.UIFigure);
            app.FinalVerdictEditFieldLabel.Text = 'Final Verdict:';
            app.FinalVerdict = uieditfield(app.UIFigure, 'text');
            app.FinalVerdict.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end